classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        YEditField_2                   matlab.ui.control.EditField
        YEditField_2Label              matlab.ui.control.Label
        PEditField                     matlab.ui.control.EditField
        PEditFieldLabel                matlab.ui.control.Label
        REditField                     matlab.ui.control.EditField
        REditFieldLabel                matlab.ui.control.Label
        Lamp_4                         matlab.ui.control.Lamp
        Lamp_3                         matlab.ui.control.Lamp
        RemoveCDButton                 matlab.ui.control.Button
        RemoveLCButton                 matlab.ui.control.Button
        ForceCDButton                  matlab.ui.control.Button
        ForceLCButton                  matlab.ui.control.Button
        EditField6                     matlab.ui.control.NumericEditField
        EditField5                     matlab.ui.control.NumericEditField
        EditField4                     matlab.ui.control.NumericEditField
        EditField3                     matlab.ui.control.NumericEditField
        EditField2                     matlab.ui.control.NumericEditField
        EditField1                     matlab.ui.control.NumericEditField
        Lamp_2                         matlab.ui.control.Lamp
        Lamp                           matlab.ui.control.Lamp
        EndDemonstrationButton         matlab.ui.control.Button
        RobotDropDown                  matlab.ui.control.DropDown
        RobotDropDownLabel             matlab.ui.control.Label
        Joint6Slider                   matlab.ui.control.Slider
        Joint6SliderLabel              matlab.ui.control.Label
        Joint5Slider                   matlab.ui.control.Slider
        Joint5Slider_2Label            matlab.ui.control.Label
        Joint4Slider                   matlab.ui.control.Slider
        Joint4Slider_2Label            matlab.ui.control.Label
        Joint3Slider                   matlab.ui.control.Slider
        Joint3Slider_2Label            matlab.ui.control.Label
        Joint2Slider                   matlab.ui.control.Slider
        Joint2Slider_2Label            matlab.ui.control.Label
        Joint1Slider                   matlab.ui.control.Slider
        Joint1Slider_2Label            matlab.ui.control.Label
        StartTeachButton               matlab.ui.control.Button
        StartBlackjackButton           matlab.ui.control.Button
        HitButton                      matlab.ui.control.Button
        StandButton                    matlab.ui.control.Button
        QueueofCoordinatesSentTextAreaLabel_2  matlab.ui.control.Label
        SendCartesianCoordinateButton  matlab.ui.control.Button
        ZEditField                     matlab.ui.control.NumericEditField
        ZEditFieldLabel                matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        XEditFieldLabel                matlab.ui.control.Label
        ContinueButton                 matlab.ui.control.Button
        EmergencyStopButton            matlab.ui.control.Button
    end

    
    properties (Access = public)        
        arduino = 0;                    % Property to store the Arduino object
        auboI5;                     % Property to store the Aubo i5 object
        dobotMagician;              % Property to store the Dobot Magican object
        hand;                       % Property to store the Hand (Collision Object) object
        playingCards;               % Property to store the Playing Cards object
        labAssessment2;             % Property to store the lab assessment 2 general object
        logFile;                    % Variable to store the log file created
        fig;                        % Variable to store the figure to plot within
        currentRobot;               % Variable to store what robot is currrently able to be controlled
        
        cardNum = 1;                % Variable to store what card to distribute
        player = 1;                 % Variable to store what player to deal cards to
        simulationMode;             % Variable to store what mode the gui is in
        eStopPressed = false;       % Variable to store if the estop has been hit or not
        continuePressed = false;    % Variable to store if the continue button has been hit or not
        blackjackDemoStarted = false; % Variable to store if the blackjack demo has been started prior (prevents repeated card dealing)
    end
    
    methods (Access = public)
        % Function to get the last card that already been distributed
        function endCardIndex = GetEndCardIndex(app)
            for i = size(app.playingCards.cardModels,2):-1:1
                % Checking if its empty
                if isempty(app.playingCards.cardModels{i})
                    continue; % Check next index for card
                else
                    endCardIndex = i; % Getting the index number of the card
                    break; % Ending function when index found
                end
            end
        end

        % Function to notify the gui that the last card has been distributed
        function NotifyCardDelt(app)
            % Emptying the last card in the cardModels array
            cardIndex = app.GetEndCardIndex();
            app.playingCards.cardModels{cardIndex} = [];
        end

        % Function to determine e-stop activity based off arduino reading
        function RealEstopReading(app, eStopValue)
            % Checking if the arduino value is the same as the current
            % eStopPressed gui property value
            % Indicates that the arduino estop has been pressed
            if eStopValue ~= app.eStopPressed
                % Call estop callback to update value
                app.EmergencyStopButtonPushed();
            end
        end  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Creating the figure showing to show the main demo
            app.fig = figure(1); % Creating figure to simulate robots
            hold on; axis([-1 1.5 -1.25 1.25 -0.76 1.5]); camlight;

            % Creating a log file for debugging
            app.logFile = log4matlab('logFile.log');
            app.logFile.SetCommandWindowLevel(app.logFile.DEBUG); % Setting the log level to debug

            % Creating the Environment
            LabAssessment2.CreateEnvironment(app.logFile);
            
            % Creating objects for various classes
            try app.arduino = Arduino(); end
            app.auboI5 = AuboI5(eye(4), app.logFile);
            app.dobotMagician = DMagician(eye(4) * transl(0,0.5,0), app.logFile);
            app.hand = Hand(eye(4) * transl(2,2,2), app.logFile);
            app.playingCards = PlayingCards(eye(4) * transl(0.15, 0.7, 0.01), app.logFile);
            app.labAssessment2 = LabAssessment2();

            % Setting the default current robot to be controlled
            app.currentRobot = app.auboI5;
            app.RobotDropDownValueChanged(); % Updating the values of the sliders
        end

        % Button pushed function: SendCartesianCoordinateButton
        function SendCartesianCoordinateButtonPushed(app, event)
            % Getting the x,y,z values to create a coordiante point
            newCoordinate = [app.XEditField.Value, app.YEditField.Value, app.ZEditField.Value];
            newOrientation = deg2rad([str2double(app.REditField.Value), str2double(app.PEditField.Value), str2double(app.YEditField_2.Value)]);

            % Switch case based off selected robot to manipulate
            switch app.RobotDropDown.Value
                case 'Aubo I5'
                    % Updating the cartesian goal of the Aubo i5
                    app.auboI5.MoveToCartesian(newCoordinate, newOrientation);
                    app.logFile.mlog = {app.logFile.DEBUG,'SendCartesianCoordinateButtonPushed', ...
                    'Cartesian goal sent to the Aubo i5'};

                case 'Dobot Magician'
                    % Updating the cartesian goal of the Dobot Magician
                    app.dobotMagician.MoveToCartesian(newCoordinate);
                    app.logFile.mlog = {app.logFile.DEBUG,'SendCartesianCoordinateButtonPushed', ...
                    'Cartesian goal sent to the Dobot Magician'};
            end
        end

        % Button pushed function: EmergencyStopButton
        function EmergencyStopButtonPushed(app, event)
            % Updating e-stop property to opposite of its prior state
            app.eStopPressed = ~app.eStopPressed;

            % if e-stop has been pressed on, start/continue must turn false
            if app.eStopPressed
                % Setting continue button to false (for two step starting)
                app.continuePressed = false;
                app.Lamp_3.Color = [1 0 0]; % Updating colour of GUI lamp
                app.Lamp_4.Color = [0.8 0.8 0.8]; % Updating colour of lamp
                app.logFile.mlog = {app.logFile.DEBUG,'EmergencyStopButtonPushed', ...
                    'Emergency stop pressed, robot function paused'};
                if app.arduino ~= 0
                    app.arduino.EstopOn(); % Forcing arduino estop on
                end
            else
                if app.arduino ~= 0
                    app.arduino.EstopOff(); 
                end % Forcing arduino estop off
                % Logging that continue button can be pressed now (e-stop off)
                app.Lamp_3.Color = [0.8 0.8 0.8]; % Updating colour of GUI lamp
                app.logFile.mlog = {app.logFile.DEBUG,'EmergencyStopButtonPushed', ...
                    'Emergency stop turned off, press continue button when ready'};
            end
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            % Checking if the e-stop is off (meaning robot can start/continue)
            if ~app.eStopPressed
                % Toggling continuePressed property to opposite state
                app.continuePressed = true;
                app.Lamp_4.Color = [0 1 0]; % Updating colour of GUI lamp
                app.logFile.mlog = {app.logFile.DEBUG,'StartContinueButtonPushed', ...
                    'Robot function started/continued'};
            else
                % Logging that e-stop is on and therefore robots cannot operate
                app.logFile.mlog = {app.logFile.DEBUG,'StartContinueButtonPushed', ...
                    'Cannot start/continue robot operation if e-stop has been pressed'};
            end
        end

        % Value changed function: RobotDropDown
        function RobotDropDownValueChanged(app, event)
            % Enabling/Disabling joint 6 movement depending on if
            % Aubo i5 or Dobot Magician is selected
            switch app.RobotDropDown.Value
                case 'Aubo I5'
                    % Setting current robot property to the Aubo i5
                    app.currentRobot = app.auboI5;

                    % Enabling joint 6 movement
                    app.Joint6Slider.Enable = true;

                    % Updating Slider Limits
                    app.Joint1Slider.Limits = [-90,  90];
                    app.Joint2Slider.Limits = [5,    175];
                    app.Joint3Slider.Limits = [-175, 175];
                    app.Joint4Slider.Limits = [-350, 360];
                    app.Joint5Slider.Limits = [-175, 175];
                    app.Joint6Slider.Limits = [-360, 360];

                    % Setting slider values to current aubo angles
                    currentJointAngles = rad2deg(app.auboI5.model.getpos());
                    app.Joint1Slider.Value = currentJointAngles(1);
                    app.Joint2Slider.Value = currentJointAngles(2);
                    app.Joint3Slider.Value = currentJointAngles(3);
                    app.Joint4Slider.Value = currentJointAngles(4);
                    app.Joint5Slider.Value = currentJointAngles(5);
                    app.Joint6Slider.Value = currentJointAngles(6);

                    app.EditField1.Value = currentJointAngles(1);
                    app.EditField2.Value = currentJointAngles(2);
                    app.EditField3.Value = currentJointAngles(3);
                    app.EditField4.Value = currentJointAngles(4);
                    app.EditField5.Value = currentJointAngles(5);
                    app.EditField6.Value = currentJointAngles(6);

                    % Updating the cartesian movement boxes with current values
                    currentTr = app.auboI5.model.fkine(app.auboI5.model.getpos()).T;
                    currentRPY = tr2rpy(currentTr);
                    currentRPY = rad2deg(currentRPY);

                    app.XEditField.Value = currentTr(1,4);
                    app.YEditField.Value = currentTr(2,4);
                    app.ZEditField.Value = currentTr(3,4);
                    app.REditField.Value = num2str(currentRPY(1,1));
                    app.PEditField.Value = num2str(currentRPY(1,2));
                    app.YEditField_2.Value = num2str(currentRPY(1,3));

                    % Disabling/Enabling Cartesian Movement Boxes
                    app.XEditField.Enable = 'on';
                    app.YEditField.Enable = 'on';
                    app.ZEditField.Enable = 'on';
                    app.REditField.Enable = 'on';
                    app.PEditField.Enable = 'on';
                    app.YEditField_2.Enable = 'on';

                    app.logFile.mlog = {app.logFile.DEBUG,'RobotDropDownValueChanged', ...
                    'Changed to Aubo i5 control'};

                case 'Dobot Magician'
                    % Setting current robot property to the dobot magician
                    app.currentRobot = app.dobotMagician;

                    % Disabling joint 6 movement
                    app.Joint6Slider.Enable = false;

                    % Updating Slider Limits
                    app.Joint1Slider.Limits = [-135, 135];
                    app.Joint2Slider.Limits = [5,    80];
                    app.Joint3Slider.Limits = [-5,   85];
                    app.Joint4Slider.Limits = [-180, 180];
                    app.Joint5Slider.Limits = [-85,  85];

                    % Setting slider values to current aubo angles
                    currentJointAngles = rad2deg(app.dobotMagician.model.getpos());
                    app.Joint1Slider.Value = currentJointAngles(1);
                    app.Joint2Slider.Value = currentJointAngles(2);
                    app.Joint3Slider.Value = currentJointAngles(3);
                    app.Joint4Slider.Value = currentJointAngles(4);
                    app.Joint5Slider.Value = currentJointAngles(5);

                    app.EditField1.Value = currentJointAngles(1);
                    app.EditField2.Value = currentJointAngles(2);
                    app.EditField3.Value = currentJointAngles(3);
                    app.EditField4.Value = currentJointAngles(4);
                    app.EditField5.Value = currentJointAngles(5);
                    app.EditField6.Value = 0;

                    % Updating the cartesian movement boxes with current values
                    currentTr = app.dobotMagician.model.fkine(app.dobotMagician.model.getpos()).T;
                    currentRPY = tr2rpy(currentTr);
                    currentRPY = rad2deg(currentRPY);

                    app.XEditField.Value = currentTr(1,4);
                    app.YEditField.Value = currentTr(2,4);
                    app.ZEditField.Value = currentTr(3,4);
                    app.REditField.Value = num2str(currentRPY(1,1));
                    app.PEditField.Value = num2str(currentRPY(1,2));
                    app.YEditField_2.Value = num2str(currentRPY(1,3));
                    
                    % Disabling/Enabling Cartesian Movement Boxes
                    app.XEditField.Enable = 'on';
                    app.YEditField.Enable = 'on';
                    app.ZEditField.Enable = 'on';
                    app.REditField.Enable = 'off';
                    app.PEditField.Enable = 'off';
                    app.YEditField_2.Enable = 'off';

                    app.logFile.mlog = {app.logFile.DEBUG,'RobotDropDownValueChanged', ...
                    'Changed to Dobot Magician control'};
            end
        end

        % Button pushed function: HitButton, StandButton
        function BlackjackButtonPressed(app, event)
            % Switch case to determine what action should be taken
            switch event.Source.Tag
                % Hit Selected
                case 'Hit'
                    % Calling the hit selected function to deal cards to chosen player
                    app.labAssessment2.HitSelected(app, false);

                    % If card number equals 6, stand is forced
                    if app.cardNum == 6
                        % Increasing player number to deal cards to the next player
                        % Setting card num back to 3 so each player can get their 3rd card
                        [app.player, app.cardNum] = app.labAssessment2.StandSelected(app.player, app.logFile);
                    end

                % Stand Selected
                case 'Stand'
                    % Calling stand selected to increase player property count
                    app.player = app.labAssessment2.StandSelected(app.player, app.logFile);
                    app.cardNum = 3; % Setting card num back to 3 so each player can get their 3rd card
            end

            % If player number equals 4, deal remaining cards to the dealer
            if app.player == 4
                % Dealing final 3 cards to the dealer
                app.labAssessment2.HitSelected(app, true);
                app.labAssessment2.HitSelected(app, true);
                app.labAssessment2.HitSelected(app, true);

                % Disabling blackjack buttons because demo has finished
                app.HitButton.Enable = "off";
                app.StandButton.Enable = "off";
            end

            app.logFile.mlog = {app.logFile.DEBUG,'BlackjackButtonPressed', ...
                    [event.Source.Tag, ' requested']};
        end

        % Button pushed function: StartBlackjackButton, StartTeachButton
        function DemonstrationModePressed(app, event)
            app.simulationMode = event.Source.Tag; % Setting simulation mode to new event tag
            app.logFile.mlog = {app.logFile.DEBUG,'DemonstrationModePressed', ...
                    [event.Source.Tag, ' requested']};

            % Switch case to change the lights for user indication on mode
            switch event.Source.Tag
                case "Blackjack"
                    % Changing the GUI display relative to the mode
                    app.Lamp_2.Color = [0.8 0.8 0.8];
                    app.Lamp.Color = [0 1 0];
                    app.HitButton.Enable = "on";
                    app.StandButton.Enable = "on";
                    app.RobotDropDown.Enable = "off";
                    app.SendCartesianCoordinateButton.Enable = "off";
                    app.Joint1Slider.Enable = "off";
                    app.Joint2Slider.Enable = "off";
                    app.Joint3Slider.Enable = "off";
                    app.Joint4Slider.Enable = "off";
                    app.Joint5Slider.Enable = "off";
                    app.Joint6Slider.Enable = "off";

                    % Setting continue pressed to true as simulation has started
                    app.continuePressed = true;
                    app.Lamp_4.Color = [0 1 0]; % Updating colour of lamp
                    
                    % Robot movements that should only occur on first
                    % starting instance of the blackjack demo
                    if app.blackjackDemoStarted == false
                        % Dealing the first two cards to each player
                        for i = 1:3
                            app.labAssessment2.HitSelected(app, false);
                            app.labAssessment2.HitSelected(app, false);
                            app.player = app.player + 1; % Increasing player value
                            app.cardNum = 1; % Re-setting card number
                        end

                        % Dealing the first two cards to the dealer
                        app.labAssessment2.HitSelected(app, true);
                        app.labAssessment2.HitSelected(app, true);
                        
                        % Re-setting player to 1 and cardNum to 3
                        app.player = 1;
                        app.cardNum = 3;

                        app.blackjackDemoStarted = true; % Preventing from this if statement code from re-occuring
                    end

                case "Teach"
                    % Changing the GUI display relative to the mode
                    app.Lamp_2.Color = [0 1 0];
                    app.Lamp.Color = [0.8 0.8 0.8];
                    app.HitButton.Enable = "off";
                    app.StandButton.Enable = "off";
                    app.RobotDropDown.Enable = "on";
                    app.SendCartesianCoordinateButton.Enable = "on";
                    app.Joint1Slider.Enable = "on";
                    app.Joint2Slider.Enable = "on";
                    app.Joint3Slider.Enable = "on";
                    app.Joint4Slider.Enable = "on";
                    app.Joint5Slider.Enable = "on";

                    app.RobotDropDownValueChanged(); % Updating the values of the sliders

                    % Switch case to determine if slider 6 should be on or off
                    switch app.RobotDropDown.Value
                        case 'Aubo I5'
                            % Turning slider 6 on for Aubo i5
                            app.Joint6Slider.Visible = "on";

                        case 'Dobot Magician'
                            % Turning slider 6 on for Aubo i5
                            app.Joint6Slider.Visible = "off";
                    end
            end
        end

        % Button pushed function: EndDemonstrationButton
        function EndDemonstrationButtonPushed(app, event)
            % Closing and deleting the app objects
            close(app.fig); delete(app);
            app.logFile.mlog = {app.logFile.DEBUG,'EndDemonstrationButtonPushed', ...
                    'End demonstration requested'};
        end

        % Value changing function: Joint1Slider, Joint2Slider, 
        % ...and 4 other components
        function SliderValueChanging(app, event)
            % Updating the edit field next to the slider to display current angle
            switch event.Source.Tag
                case '1'
                    app.EditField1.Value = event.Value;
                case '2'
                    app.EditField2.Value = event.Value;
                case '3'
                    app.EditField3.Value = event.Value;
                case '4'
                    app.EditField4.Value = event.Value;
                case '5'
                    app.EditField5.Value = event.Value;
                case '6'
                    app.EditField6.Value = event.Value;
            end

            % Switch case based off selected robot to manipulate
            switch app.RobotDropDown.Value
                case 'Aubo I5'
                    % Updating the joint angle of specified joint index
                    app.auboI5.MoveJoint(event.Source.Tag, event.Value);

                    app.logFile.mlog = {app.logFile.DEBUG,'JointSliderChanging', ...
                    'Aubo i5 joint change sent'};

                case 'Dobot Magician'
                    % Updating the joint angle of specified joint index
                    app.dobotMagician.MoveJoint(event.Source.Tag, event.Value);

                    app.logFile.mlog = {app.logFile.DEBUG,'JointSliderChanging', ...
                    'Dobot Magician joint change sent'};
            end
        end

        % Button pushed function: ForceLCButton
        function ForceLCButtonPushed(app, event)
            % Moving hand to verify light curtain into the light curtain area
            app.hand.handModels{1}.base = eye(4)*transl(0.3,0.3,0.5); 
            app.hand.handModels{1}.animate(0); % Animating the movement
            drawnow; % Updating the plot

            app.logFile.mlog = {app.logFile.DEBUG,'ForceLCButtonPushed', ...
                    'Hand moved to trip light curtain'};
        end

        % Button pushed function: RemoveLCButton
        function RemoveLCButtonPushed(app, event)
            % Moving hand to verify light curtain out of light curtain area
            app.hand.handModels{1}.base = eye(4)*transl(2,2,2); 
            app.hand.handModels{1}.animate(0); % Animating the movement
            drawnow; % Updating the plot

            app.logFile.mlog = {app.logFile.DEBUG,'RemoveLCButtonPushed', ...
                    'Hand moved outside of light curtain'};
        end

        % Button pushed function: ForceCDButton
        function ForceCDButtonPushed(app, event)
            % Moving collision hand into path of robots
            app.hand.handModels{2}.base = eye(4)*transl(0.15, 0.65, 0.2); 
            app.hand.handModels{2}.animate(0); % Animating the movement
            drawnow; % Updating the plot

            app.logFile.mlog = {app.logFile.DEBUG,'ForceCDButtonPushed', ...
                    'Hand moved to be potential collision'};
        end

        % Button pushed function: RemoveCDButton
        function RemoveCDButtonPushed(app, event)
            % Moving collision hand out of robot's paths
            app.hand.handModels{2}.base = eye(4)*transl(2,2,2); 
            app.hand.handModels{2}.animate(0); % Animating the movement
            drawnow; % Updating the plot

            app.logFile.mlog = {app.logFile.DEBUG,'RemoveCDButtonPushed', ...
                    'Hand moved to stop being potential collision'};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EmergencyStopButton = uibutton(app.UIFigure, 'push');
            app.EmergencyStopButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyStopButtonPushed, true);
            app.EmergencyStopButton.Text = 'Emergency Stop';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X ';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y ';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z ';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.SendCartesianCoordinateButton = uibutton(app.UIFigure, 'push');
            app.SendCartesianCoordinateButton.ButtonPushedFcn = createCallbackFcn(app, @SendCartesianCoordinateButtonPushed, true);
            app.SendCartesianCoordinateButton.Text = 'Send Cartesian Coordinate';
            app.QueueofCoordinatesSentTextAreaLabel_2 = uilabel(app.UIFigure);
            app.QueueofCoordinatesSentTextAreaLabel_2.Text = {'Send End-Effector to '; 'Cartesian Coordinate:'};
            app.StandButton = uibutton(app.UIFigure, 'push');
            app.StandButton.ButtonPushedFcn = createCallbackFcn(app, @BlackjackButtonPressed, true);
            app.StandButton.Tag = 'Stand';
            app.StandButton.Text = 'Stand';
            app.HitButton = uibutton(app.UIFigure, 'push');
            app.HitButton.ButtonPushedFcn = createCallbackFcn(app, @BlackjackButtonPressed, true);
            app.HitButton.Tag = 'Hit';
            app.HitButton.Text = 'Hit';
            app.StartBlackjackButton = uibutton(app.UIFigure, 'push');
            app.StartBlackjackButton.ButtonPushedFcn = createCallbackFcn(app, @DemonstrationModePressed, true);
            app.StartBlackjackButton.Tag = 'Blackjack';
            app.StartBlackjackButton.Text = 'Start Blackjack';
            app.StartTeachButton = uibutton(app.UIFigure, 'push');
            app.StartTeachButton.ButtonPushedFcn = createCallbackFcn(app, @DemonstrationModePressed, true);
            app.StartTeachButton.Tag = 'Teach';
            app.StartTeachButton.Text = 'Start Teach';
            app.Joint1Slider_2Label = uilabel(app.UIFigure);
            app.Joint1Slider_2Label.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Limits = [-175 175];
            app.Joint1Slider.MajorTicks = [];
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Joint1Slider.MinorTicks = [];
            app.Joint1Slider.Tag = '1';
            app.Joint2Slider_2Label = uilabel(app.UIFigure);
            app.Joint2Slider_2Label.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.Limits = [-175 175];
            app.Joint2Slider.MajorTicks = [];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Joint2Slider.MinorTicks = [];
            app.Joint2Slider.Tag = '2';
            app.Joint3Slider_2Label = uilabel(app.UIFigure);
            app.Joint3Slider_2Label.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.Limits = [-175 175];
            app.Joint3Slider.MajorTicks = [];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Joint3Slider.MinorTicks = [];
            app.Joint3Slider.Tag = '3';
            app.Joint4Slider_2Label = uilabel(app.UIFigure);
            app.Joint4Slider_2Label.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.UIFigure);
            app.Joint4Slider.Limits = [-175 175];
            app.Joint4Slider.MajorTicks = [];
            app.Joint4Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Joint4Slider.MinorTicks = [];
            app.Joint4Slider.Tag = '4';
            app.Joint5Slider_2Label = uilabel(app.UIFigure);
            app.Joint5Slider_2Label.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.UIFigure);
            app.Joint5Slider.Limits = [-175 175];
            app.Joint5Slider.MajorTicks = [];
            app.Joint5Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Joint5Slider.MinorTicks = [];
            app.Joint5Slider.Tag = '5';
            app.Joint6SliderLabel = uilabel(app.UIFigure);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.UIFigure);
            app.Joint6Slider.Limits = [-175 175];
            app.Joint6Slider.MajorTicks = [];
            app.Joint6Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Joint6Slider.MinorTicks = [];
            app.Joint6Slider.Tag = '6';
            app.RobotDropDownLabel = uilabel(app.UIFigure);
            app.RobotDropDownLabel.Text = 'Robot';
            app.RobotDropDown = uidropdown(app.UIFigure);
            app.RobotDropDown.Items = {'Aubo I5', 'Dobot Magician'};
            app.RobotDropDown.ValueChangedFcn = createCallbackFcn(app, @RobotDropDownValueChanged, true);
            app.RobotDropDown.Value = 'Aubo I5';
            app.EndDemonstrationButton = uibutton(app.UIFigure, 'push');
            app.EndDemonstrationButton.ButtonPushedFcn = createCallbackFcn(app, @EndDemonstrationButtonPushed, true);
            app.EndDemonstrationButton.Text = 'End Demonstration';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.EditField1 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField6 = uieditfield(app.UIFigure, 'numeric');
            app.ForceLCButton = uibutton(app.UIFigure, 'push');
            app.ForceLCButton.ButtonPushedFcn = createCallbackFcn(app, @ForceLCButtonPushed, true);
            app.ForceLCButton.Text = 'Force LC';
            app.ForceCDButton = uibutton(app.UIFigure, 'push');
            app.ForceCDButton.ButtonPushedFcn = createCallbackFcn(app, @ForceCDButtonPushed, true);
            app.ForceCDButton.Text = 'Force CD';
            app.RemoveLCButton = uibutton(app.UIFigure, 'push');
            app.RemoveLCButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveLCButtonPushed, true);
            app.RemoveLCButton.Text = 'Remove LC';
            app.RemoveCDButton = uibutton(app.UIFigure, 'push');
            app.RemoveCDButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveCDButtonPushed, true);
            app.RemoveCDButton.Text = 'Remove CD';
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_4 = uilamp(app.UIFigure);
            app.REditFieldLabel = uilabel(app.UIFigure);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.UIFigure, 'text');
            app.REditField.Value = '0';
            app.PEditFieldLabel = uilabel(app.UIFigure);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.UIFigure, 'text');
            app.PEditField.Value = '0';
            app.YEditField_2Label = uilabel(app.UIFigure);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.UIFigure, 'text');
            app.YEditField_2.Value = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end